classdef darmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uiFigure                        matlab.ui.Figure
        appTitleLabel                   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        uiAxes                          matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        iterDeltaEditField              matlab.ui.control.NumericEditField
        iterdeltaLabel                  matlab.ui.control.Label
        resultsToTxtButton              matlab.ui.control.Button
        aParameterSpinner               matlab.ui.control.Spinner
        cSpinnerLabel                   matlab.ui.control.Label
        bParameterSpinner               matlab.ui.control.Spinner
        bSpinnerLabel                   matlab.ui.control.Label
        aSpinnerLabel                   matlab.ui.control.Label
        cParameterSpinner               matlab.ui.control.Spinner
        kvSwitch                        matlab.ui.control.Switch
        infoTextArea                    matlab.ui.control.TextArea
        alphaEditField                  matlab.ui.control.NumericEditField
        znailnosttestaEditFieldLabel    matlab.ui.control.Label
        wFunctionDropDown               matlab.ui.control.DropDown
        metodaizraunauteiDropDownLabel  matlab.ui.control.Label
        dataset2StatusLamp              matlab.ui.control.Lamp
        dataset1StatusLamp              matlab.ui.control.Lamp
        loadDataset2Button              matlab.ui.control.Button
        loadDataset1Button              matlab.ui.control.Button
        BottomPanel                     matlab.ui.container.Panel
        resultTable                     matlab.ui.control.Table
    end

    % ------------------------------------------------------------
    % ---------------------- CLASS MEMBERS -----------------------
    % ------------------------------------------------------------
    properties (Access = private)
        fileIn1 = ""
        fileIn2 = ""
        lastFileOpenDialogLocation = ""
        file1IsLoaded = false;
        file2IsLoaded = false;
        canBeCalculated = false;
        dataset1 = 0
        dataset2 = 0
        wFunctionName = 'L1'
        alpha = 0.05
        iterDelta = 1e-8;
        results
        calculationDone = false
    end

    properties (Constant)
        aParameterDefaultValues = dictionary("Hampel", 1.5)
        bParameterDefaultValues = dictionary("Hampel", 3)
        cParameterDefaultValues = dictionary( ...
            ["Lp", "Huber", "HuberMod", "Fair", "Cauchy", "Welsch", "Tukey", "Hampel", "danska"], ...
            [1.2, 1.3450, 1.2107, 1.3998, 2.3849, 2.9846, 4.6851, 6, 3])
    end
    
    
    % ------------------------------------------------------------
    % ---------------------- CLASS METHODS -----------------------
    % ------------------------------------------------------------
    methods (Access = private)

        function setFileIn1(app, fileIn1_)
            app.fileIn1 = fileIn1_;
        end

        function setFileIn2(app, fileIn2_)
            app.fileIn2 = fileIn2_;
        end

        function setFile1IsLoaded(app, file1IsLoaded_)
            app.file1IsLoaded = file1IsLoaded_;
        end

        function setFile2IsLoaded(app, file2IsLoaded_)
            app.file2IsLoaded = file2IsLoaded_;
        end

        function setDataset1(app, dataset1_)
            app.dataset1 = dataset1_;
        end

        function setDataset2(app, dataset2_)
            app.dataset2 = dataset2_;
        end


        % NALOŽI DATOTEKO
        function loadFile(app, fileId)
            if fileId == 1
                statusLamp = app.dataset1StatusLamp;
                setFileIn = @app.setFileIn1;
                setFileIsLoaded = @app.setFile1IsLoaded;
                setDataset = @app.setDataset1;
            end
                
            if fileId == 2
                statusLamp = app.dataset2StatusLamp;
                setFileIn = @app.setFileIn2;
                setFileIsLoaded = @app.setFile2IsLoaded;
                setDataset = @app.setDataset2;
            end
            
            fileIsLoaded = false;
            [file, path] = uigetfile(fullfile(app.lastFileOpenDialogLocation, '.daf'));
            
            if file
                app.lastFileOpenDialogLocation = path;
                fileIn = fullfile(path, file);
                statusLamp.Color = [0.90,0.90,0.90];
                setFileIn(fileIn);
                try
                    setDataset(TerminskaIzmera.fromDafFile(fileIn));
                    setFileIsLoaded(true);
                    fileIsLoaded = true;
                catch e
                    setFileIsLoaded(false);
                    fileIsLoaded = false;
                    warndlg(e.message)
                end
            end
            
            if fileIsLoaded
                statusLamp.Color = 'green';
                app.infoTextArea.Value = generateInfoText(app);
                app.canBeCalculated = false;
            end
            
            if app.file1IsLoaded && app.file2IsLoaded
                pointNames1 = [app.dataset1.points.name];
                pointNames2 = [app.dataset2.points.name];
                if length(pointNames1) == length(pointNames2) && all(pointNames1 == pointNames2)
                    app.canBeCalculated = true;
                    app.executeCalculation();
                else
                    app.canBeCalculated = false;
                    warndlg("Naloženi datoteki ne vsebujeta identičnih točk.")
                end
            end
        end

        
        % ZGENERIRAJ BESEDILO ZA INFO TEXTBOX
        function text = generateInfoText(app, tCritValue, nIter)
            [~,name,ext] = fileparts(app.fileIn1);
            file1Name = [name ext];
            [~,name,ext] = fileparts(app.fileIn2);
            file2Name = [name ext];
            if nargin == 1
                text = sprintf(['%s\n' ...
                                '%s\n'], file1Name, file2Name);
            else
               text = sprintf(['%s\n' ...
                                '%s\n' ...
                                'T_krit = %.4f\n' ...
                                'št. iteracij: %d'], file1Name, file2Name, tCritValue, nIter);
            end
        end

        
        % NAREDI DEFORMACIJSKO ANALIZO IN POKLIČI PRIKAZ REZULTATOV
        function executeCalculation(app)
            try
                app.results = darm( ...
                    app.dataset1, ...
                    app.dataset2, ...
                    app.wFunctionName, ...
                    app.aParameterSpinner.Value, ...
                    app.bParameterSpinner.Value, ...
                    app.cParameterSpinner.Value, ...
                    app.alpha, ...
                    app.iterDelta ...
                    );
    
                app.calculationDone = true;
                app.resultsToTxtButton.Enable = true;
                app.showResults()
            
            catch e
                warndlg(e.message)
            end
        end

        
        % PRIKAŽI REZULTATE (PREGLEDNICA + IZRIS + INFO TEXTBOX)
        function showResults(app)
            % napolni preglednico
            app.fillResultTable();

            switch app.kvSwitch.Value
                case 'K'
                    displacements = [app.results.perComponents.d(1:2:end), app.results.perComponents.d(2:2:end)];
                    tCritValue = app.results.perComponents.tCritValue;
                    nIter = app.results.perComponents.nIter;
                case 'V'
                    displacements = [app.results.perPoints.d(1:2:end), app.results.perPoints.d(2:2:end)];
                    tCritValue = app.results.perPoints.tCritValue;
                    nIter = app.results.perPoints.nIter;
            end
            
            % izriši skico
            plotDisplacements( ...
                app.uiAxes, ...
                [app.dataset1.points.yAdj]', ...
                [app.dataset1.points.xAdj]', ...
                displacements(:,1), ...
                displacements(:,2), ...
                [app.dataset1.points.name]', ...
                1 ...
                );
            
            % napolni info textbox
            app.infoTextArea.Value = generateInfoText(app, tCritValue, nIter);
        end
   
        
        % NAPOLNI TABELO Z REZULTATI
        function fillResultTable(app)
            switch app.kvSwitch.Value
                % analiza stabilnosti po komponentah (PC)
                case 'K'
                    t = table( ...
                        app.results.pointNames, ...
                        app.results.perComponents.d(1:2:end), ...
                        app.results.perComponents.tStatistic(:,1), ...
                        app.results.perComponents.stableComponents(:,1), ...
                        app.results.perComponents.d(2:2:end), ...
                        app.results.perComponents.tStatistic(:,2), ...
                        app.results.perComponents.stableComponents(:,2), ...
                        app.results.perComponents.s, ...
                        repmat({''},length(app.results.pointNames),1), ...
                        app.results.perComponents.stablePoints, ...
                        true(length(app.results.pointNames), 1)...
                        );
                
                % analiza stabilnosti po točkah (PP)
                case 'V'
                    t = table( ...
                        app.results.pointNames, ...
                        app.results.perPoints.d(1:2:end), ...
                        repmat({''},length(app.results.pointNames),1), ...
                        repmat({''},length(app.results.pointNames),1), ...
                        app.results.perPoints.d(2:2:end), ...
                        repmat({''},length(app.results.pointNames),1), ...
                        repmat({''},length(app.results.pointNames),1), ...
                        app.results.perPoints.s(1:end), ...
                        app.results.perPoints.tStatistic, ...
                        app.results.perPoints.stablePoints, ...
                        true(length(app.results.pointNames), 1)...
                        );
            end
            app.resultTable.Data = t;
        end
    end


    % ------------------------------------------------------------
    % ------------------------ CALLBACKS -------------------------
    % ------------------------------------------------------------

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~any(any(contains(struct2cell(ver), 'Statistics and Machine Learning Toolbox')))
                warndlg("Program za svoje delovanje potrebuje Statistics and Machine Learning Toolbox.");
                delete(app);
            end

            if ~(version('-release') == "2023b")
                warndlg("Program za svoje delovanje potrebuje različico MATLAB-a 2023b ali novejšo.");
                delete(app);
            end
        end

        % Button pushed function: loadDataset1Button
        function loadDataset1ButtonPushed(app, event)
            app.loadFile(1);
        end

        % Button pushed function: loadDataset2Button
        function loadDataset2ButtonPushed(app, event)
            app.loadFile(2);
        end

        % Value changed function: wFunctionDropDown
        function wFunctionDropDownValueChanged(app, event)
            app.wFunctionName = app.wFunctionDropDown.Value;

            if ismember(app.wFunctionName, app.aParameterDefaultValues.keys)
                app.aParameterSpinner.Value = app.aParameterDefaultValues(app.wFunctionName);
                app.aParameterSpinner.Enable = true;
            else
                app.aParameterSpinner.Value = 0;
                app.aParameterSpinner.Enable = false;
            end

            if ismember(app.wFunctionName, app.bParameterDefaultValues.keys)
                app.bParameterSpinner.Value = app.bParameterDefaultValues(app.wFunctionName);
                app.bParameterSpinner.Enable = true;
            else
                app.bParameterSpinner.Value = 0;
                app.bParameterSpinner.Enable = false;
            end

            if ismember(app.wFunctionName, app.cParameterDefaultValues.keys)
                app.cParameterSpinner.Value = app.cParameterDefaultValues(app.wFunctionName);
                app.cParameterSpinner.Enable = true;
            else
                app.cParameterSpinner.Value = 0;
                app.cParameterSpinner.Enable = false;
            end

            if app.canBeCalculated
                app.executeCalculation();
            end
        end

        % Value changed function: aParameterSpinner
        function aParameterSpinnerValueChanged(app, event)
            if app.canBeCalculated
                app.executeCalculation();
            end
        end

        % Value changed function: bParameterSpinner
        function bParameterSpinnerValueChanged(app, event)
            if app.canBeCalculated
                app.executeCalculation();
            end
        end

        % Value changed function: cParameterSpinner
        function cParameterSpinnerValueChanged(app, event)
            if app.canBeCalculated
                app.executeCalculation();
            end
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            app.alpha = app.alphaEditField.Value / 100;
            if app.canBeCalculated
                app.executeCalculation();
            end
        end

        % Value changed function: kvSwitch
        function kvSwitchValueChanged(app, event)
            if app.calculationDone
                app.showResults()
            end
        end

        % Button pushed function: resultsToTxtButton
        function resultsToTxtButtonPushed(app, event)
            [file, path] = uiputfile('*.txt');
            if ~(isequal(file,0) || isequal(path,0))
                fileOut = fullfile(path, file);
                writeResultsToTxt(fileOut, app.results, app.fileIn1, app.fileIn2)
            end
            % imageOut = strrep(fileOut,'.txt','.png');
            % exportgraphics(app.uiAxes, imageOut);
        end

        % Cell edit callback: resultTable
        function resultTableCellEdit(app, event)
            switch app.kvSwitch.Value
                case 'K'
                    displacements = [app.results.perComponents.d(1:2:end), app.results.perComponents.d(2:2:end)];
                case 'V'
                    displacements = [app.results.perPoints.d(1:2:end), app.results.perPoints.d(2:2:end)];
            end

            plotDisplacements( ...
                app.uiAxes, ...
                [app.dataset1.points.yAdj]', ...
                [app.dataset1.points.xAdj]', ...
                displacements(:,1), ...
                displacements(:,2), ...
                [app.dataset1.points.name]', ...
                app.resultTable.Data.Var11 ...
                );
        end

        % Value changed function: iterDeltaEditField
        function iterDeltaEditFieldValueChanged(app, event)
            app.iterDelta = 10^app.iterDeltaEditField.Value;
            if app.canBeCalculated
                app.executeCalculation();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uiFigure = uifigure('Visible', 'off');
            app.uiFigure.Name = 'MATLAB App';
            app.BottomPanel = uipanel(app.uiFigure);
            app.resultTable = uitable(app.BottomPanel);
            app.resultTable.ColumnName = {'tocka'; 'Δy [m]'; 'Ty'; 'stabilnost y'; 'Δx [m]'; 'Tx'; 'stabilnost x'; 'Δs [m]'; 'T'; 'stabilnost'; 'izris'};
            app.resultTable.RowName = {};
            app.resultTable.ColumnEditable = [false false false false false false false false false false true];
            app.resultTable.CellEditCallback = createCallbackFcn(app, @resultTableCellEdit, true);
            app.LeftPanel = uipanel(app.uiFigure);
            app.loadDataset1Button = uibutton(app.LeftPanel, 'push');
            app.loadDataset1Button.ButtonPushedFcn = createCallbackFcn(app, @loadDataset1ButtonPushed, true);
            app.loadDataset1Button.Text = 'izmera 1';
            app.loadDataset2Button = uibutton(app.LeftPanel, 'push');
            app.loadDataset2Button.ButtonPushedFcn = createCallbackFcn(app, @loadDataset2ButtonPushed, true);
            app.loadDataset2Button.Text = 'izmera 2';
            app.dataset1StatusLamp = uilamp(app.LeftPanel);
            app.dataset2StatusLamp = uilamp(app.LeftPanel);
            app.metodaizraunauteiDropDownLabel = uilabel(app.LeftPanel);
            app.metodaizraunauteiDropDownLabel.Text = 'metoda izračuna uteži';
            app.wFunctionDropDown = uidropdown(app.LeftPanel);
            app.wFunctionDropDown.Items = {'L1', 'L1-L2', 'Lp', 'Huberjeva ocena', 'modificirana Huberjeva ocena', 'Fairova ocena', 'Cauchyjeva ocena', 'Welscheva ocena', 'Tukeyeva ocena', 'German-McClurejeva ocena', 'Hampelova ocena', 'danska ocena'};
            app.wFunctionDropDown.ItemsData = {'L1', 'L1-L2', 'Lp', 'Huber', 'HuberMod', 'Fair', 'Cauchy', 'Welsch', 'Tukey', 'German-McClure', 'Hampel', 'danska'};
            app.wFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @wFunctionDropDownValueChanged, true);
            app.wFunctionDropDown.Value = 'L1';
            app.znailnosttestaEditFieldLabel = uilabel(app.LeftPanel);
            app.znailnosttestaEditFieldLabel.Text = 'značilnost testa [%]';
            app.alphaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.alphaEditField.Limits = [0 100];
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 5;
            app.infoTextArea = uitextarea(app.LeftPanel);
            app.infoTextArea.Editable = 'off';
            app.kvSwitch = uiswitch(app.LeftPanel, 'slider');
            app.kvSwitch.Items = {'K', 'V'};
            app.kvSwitch.ValueChangedFcn = createCallbackFcn(app, @kvSwitchValueChanged, true);
            app.kvSwitch.Value = 'K';
            app.cParameterSpinner = uispinner(app.LeftPanel);
            app.cParameterSpinner.Step = 0.1;
            app.cParameterSpinner.ValueDisplayFormat = '%11.5g';
            app.cParameterSpinner.ValueChangedFcn = createCallbackFcn(app, @cParameterSpinnerValueChanged, true);
            app.cParameterSpinner.Enable = 'off';
            app.aSpinnerLabel = uilabel(app.LeftPanel);
            app.aSpinnerLabel.Text = 'a';
            app.bSpinnerLabel = uilabel(app.LeftPanel);
            app.bSpinnerLabel.Text = 'b';
            app.bParameterSpinner = uispinner(app.LeftPanel);
            app.bParameterSpinner.Step = 0.1;
            app.bParameterSpinner.ValueDisplayFormat = '%11.5g';
            app.bParameterSpinner.ValueChangedFcn = createCallbackFcn(app, @bParameterSpinnerValueChanged, true);
            app.bParameterSpinner.Enable = 'off';
            app.cSpinnerLabel = uilabel(app.LeftPanel);
            app.cSpinnerLabel.Text = 'c';
            app.aParameterSpinner = uispinner(app.LeftPanel);
            app.aParameterSpinner.Step = 0.1;
            app.aParameterSpinner.ValueDisplayFormat = '%11.5g';
            app.aParameterSpinner.ValueChangedFcn = createCallbackFcn(app, @aParameterSpinnerValueChanged, true);
            app.aParameterSpinner.Enable = 'off';
            app.resultsToTxtButton = uibutton(app.LeftPanel, 'push');
            app.resultsToTxtButton.ButtonPushedFcn = createCallbackFcn(app, @resultsToTxtButtonPushed, true);
            app.resultsToTxtButton.Enable = 'off';
            app.resultsToTxtButton.Text = 'TXT POROČILO';
            app.iterdeltaLabel = uilabel(app.LeftPanel);
            app.iterdeltaLabel.Text = 'iter delta                   1e';
            app.iterDeltaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.iterDeltaEditField.Limits = [-12 -4];
            app.iterDeltaEditField.ValueDisplayFormat = '%.0f';
            app.iterDeltaEditField.ValueChangedFcn = createCallbackFcn(app, @iterDeltaEditFieldValueChanged, true);
            app.iterDeltaEditField.Value = -8;
            app.RightPanel = uipanel(app.uiFigure);
            app.uiAxes = uiaxes(app.RightPanel);
            xlabel(app.uiAxes, 'y [m]')
            ylabel(app.uiAxes, 'x [m]')
            app.appTitleLabel = uilabel(app.uiFigure);
            app.appTitleLabel.Text = 'DEFORMACIJSKA ANALIZA Z ROBUSTNIMI METODAMI';
            % Show the figure after all components are created
            app.uiFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = darmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uiFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uiFigure)
        end
    end
end